classdef uireactivelistExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReactiveListUIFigure         matlab.ui.Figure
        List1Panel                   matlab.ui.container.Panel
        Load1                        matlab.ui.control.Button
        Load2                        matlab.ui.control.Button
        DropDownLabel                matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        EditFieldLabel               matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        ListBox1Label                matlab.ui.control.Label
        ListBox1                     matlab.ui.control.ListBox
        List2withItemsDataPanel      matlab.ui.container.Panel
        ListBox2Label                matlab.ui.control.Label
        ListBox2                     matlab.ui.control.ListBox
        SliderLabel                  matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        SpinnerLabel                 matlab.ui.control.Label
        Spinner                      matlab.ui.control.Spinner
        List3withcontrolsPanel       matlab.ui.container.Panel
        ListBox3Label                matlab.ui.control.Label
        ListBox3                     matlab.ui.control.ListBox
        AddItem                      matlab.ui.control.Button
        DeleteItem                   matlab.ui.control.Button
        MoveUp                       matlab.ui.control.Button
        MoveDown                     matlab.ui.control.Button
        DropDown2Label               matlab.ui.control.Label
        DropDown2                    matlab.ui.control.DropDown
        SelectedValueEditFieldLabel  matlab.ui.control.Label
        SelectedValue                matlab.ui.control.EditField
        WithoutItemsDataButton       matlab.ui.control.Button
        WithItemsDataButton          matlab.ui.control.Button
        MultiselectSwitchLabel       matlab.ui.control.Label
        MultiselectSwitch            matlab.ui.control.Switch
    end

    
    properties (Access = private)
        ReactiveList1 % Description
        ReactiveList2 % Description
        ReactiveList3 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            items1 = "Item " + (1:5)';
            app.DropDown.Items = items1;
            % List 1
            app.ReactiveList1 = UI.ReactiveList(items1);
            app.ReactiveList1.bind(app.ListBox1);
            app.ReactiveList1.bindValue(app.EditField);
            app.ReactiveList1.bindValue(app.DropDown);
            % List 2
            items2 = "Item " + (1:10);
            items2Data = 1:10;
            app.ReactiveList2 = UI.ReactiveList(items2);
            app.ReactiveList2.bind(app.ListBox2);
            app.ReactiveList2.setItemsData(items2Data);
            app.ReactiveList2.bindValue(app.Spinner);
            app.ReactiveList2.bindValue(app.Slider);
            % List 3
            app.WithoutItemsDataButtonPushed();
        end

        % Value changed function: ListBox1
        function ListBox1ValueChanged(app, event)
            app.ReactiveList1.select();
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.ReactiveList1.updateValue(event);
        end

        % Button pushed function: Load1
        function Load1Pushed(app, event)
            items = "Item " + (1:5)';
            app.DropDown.Items = items;
            app.ReactiveList1.update(items);
        end

        % Button pushed function: Load2
        function Load2Pushed(app, event)
            items = ["Red" "Green" "Blue"]';
            app.DropDown.Items = items;
            app.ReactiveList1.update(items);
        end

        % Value changed function: ListBox2
        function ListBox2ValueChanged(app, event)
            app.ReactiveList2.select();
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.ReactiveList2.updateValue(event);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Value = round(app.Slider.Value);
            app.ReactiveList2.updateValue(event);
        end

        % Button pushed function: AddItem
        function AddItemButtonPushed(app, event)
            app.ReactiveList3.addItem();
        end

        % Button pushed function: DeleteItem
        function DeleteItemButtonPushed(app, event)
            app.ReactiveList3.deleteItem();
        end

        % Button pushed function: MoveUp
        function MoveUpButtonPushed(app, event)
            app.ReactiveList3.moveItemUp();
        end

        % Button pushed function: MoveDown
        function MoveDownButtonPushed(app, event)
            app.ReactiveList3.moveItemDown();
        end

        % Value changed function: ListBox3
        function ListBox3ValueChanged(app, event)
            app.ReactiveList3.select();
        end

        % Button pushed function: WithoutItemsDataButton
        function WithoutItemsDataButtonPushed(app, event)
            app.ReactiveList3 = UI.ReactiveList(["Item 1" "Item 2"]);
            app.ReactiveList3.bind(app.ListBox3, app.DropDown2);
            app.ReactiveList3.setItemsData([]);
%             app.ReactiveList3.bindValue(app.SelectedValue);
        end

        % Button pushed function: WithItemsDataButton
        function WithItemsDataButtonPushed(app, event)
            app.ReactiveList3 = UI.ReactiveList(["Item 1" "Item 2"]);
            app.ReactiveList3.bind(app.ListBox3, app.DropDown2);
            app.ReactiveList3.setItemsData([1 2]);
%             app.ReactiveList3.bindValue(app.SelectedValue);
        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            app.ReactiveList3.select(event);
        end

        % Value changed function: MultiselectSwitch
        function MultiselectSwitchValueChanged(app, event)
            value = app.MultiselectSwitch.Value;
            app.ListBox3.Multiselect = value;
            if value
                app.ReactiveList3.unbind(app.DropDown2);
            else
                app.ReactiveList3.bind(app.DropDown2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReactiveListUIFigure = uifigure('Visible', 'off');
            app.ReactiveListUIFigure.Name = 'Reactive List';
            app.List1Panel = uipanel(app.ReactiveListUIFigure);
            app.List1Panel.Title = 'List 1';
            app.Load1 = uibutton(app.List1Panel, 'push');
            app.Load1.ButtonPushedFcn = createCallbackFcn(app, @Load1Pushed, true);
            app.Load1.Text = 'Load Items';
            app.Load2 = uibutton(app.List1Panel, 'push');
            app.Load2.ButtonPushedFcn = createCallbackFcn(app, @Load2Pushed, true);
            app.Load2.Text = 'Load Colors';
            app.DropDownLabel = uilabel(app.List1Panel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.List1Panel);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.EditFieldLabel = uilabel(app.List1Panel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.List1Panel, 'text');
            app.EditField.Editable = 'off';
            app.ListBox1Label = uilabel(app.List1Panel);
            app.ListBox1Label.Text = 'List Box 1';
            app.ListBox1 = uilistbox(app.List1Panel);
            app.ListBox1.ValueChangedFcn = createCallbackFcn(app, @ListBox1ValueChanged, true);
            app.List2withItemsDataPanel = uipanel(app.ReactiveListUIFigure);
            app.List2withItemsDataPanel.Title = 'List 2 with Items Data';
            app.ListBox2Label = uilabel(app.List2withItemsDataPanel);
            app.ListBox2Label.Text = 'List Box 2';
            app.ListBox2 = uilistbox(app.List2withItemsDataPanel);
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @ListBox2ValueChanged, true);
            app.SliderLabel = uilabel(app.List2withItemsDataPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.List2withItemsDataPanel);
            app.Slider.Limits = [1 10];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.SpinnerLabel = uilabel(app.List2withItemsDataPanel);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.List2withItemsDataPanel);
            app.Spinner.Limits = [1 10];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.List3withcontrolsPanel = uipanel(app.ReactiveListUIFigure);
            app.List3withcontrolsPanel.Title = 'List 3 with controls';
            app.ListBox3Label = uilabel(app.List3withcontrolsPanel);
            app.ListBox3Label.Text = 'List Box 3';
            app.ListBox3 = uilistbox(app.List3withcontrolsPanel);
            app.ListBox3.Items = {'Item 1', 'Item 2'};
            app.ListBox3.ValueChangedFcn = createCallbackFcn(app, @ListBox3ValueChanged, true);
            app.AddItem = uibutton(app.List3withcontrolsPanel, 'push');
            app.AddItem.ButtonPushedFcn = createCallbackFcn(app, @AddItemButtonPushed, true);
            app.AddItem.Text = 'Add Item';
            app.DeleteItem = uibutton(app.List3withcontrolsPanel, 'push');
            app.DeleteItem.ButtonPushedFcn = createCallbackFcn(app, @DeleteItemButtonPushed, true);
            app.DeleteItem.Text = 'Delete Item';
            app.MoveUp = uibutton(app.List3withcontrolsPanel, 'push');
            app.MoveUp.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUp.Text = 'Move Up';
            app.MoveDown = uibutton(app.List3withcontrolsPanel, 'push');
            app.MoveDown.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDown.Text = 'Move Down';
            app.DropDown2Label = uilabel(app.List3withcontrolsPanel);
            app.DropDown2Label.Text = 'Drop Down2';
            app.DropDown2 = uidropdown(app.List3withcontrolsPanel);
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.SelectedValueEditFieldLabel = uilabel(app.List3withcontrolsPanel);
            app.SelectedValueEditFieldLabel.Text = 'Selected Value';
            app.SelectedValue = uieditfield(app.List3withcontrolsPanel, 'text');
            app.SelectedValue.Editable = 'off';
            app.WithoutItemsDataButton = uibutton(app.List3withcontrolsPanel, 'push');
            app.WithoutItemsDataButton.ButtonPushedFcn = createCallbackFcn(app, @WithoutItemsDataButtonPushed, true);
            app.WithoutItemsDataButton.Text = 'Without Items Data';
            app.WithItemsDataButton = uibutton(app.List3withcontrolsPanel, 'push');
            app.WithItemsDataButton.ButtonPushedFcn = createCallbackFcn(app, @WithItemsDataButtonPushed, true);
            app.WithItemsDataButton.Text = 'With Items Data';
            app.MultiselectSwitchLabel = uilabel(app.List3withcontrolsPanel);
            app.MultiselectSwitchLabel.Text = 'Multiselect';
            app.MultiselectSwitch = uiswitch(app.List3withcontrolsPanel, 'slider');
            app.MultiselectSwitch.ValueChangedFcn = createCallbackFcn(app, @MultiselectSwitchValueChanged, true);
            % Show the figure after all components are created
            app.ReactiveListUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uireactivelistExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReactiveListUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReactiveListUIFigure)
        end
    end
end